classdef GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        ShowCirclesButton               matlab.ui.control.StateButton
        ContrastSliderLabel             matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        BrightnessSliderLabel           matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        UIAxes13                        matlab.ui.control.UIAxes
        UIAxes14                        matlab.ui.control.UIAxes
        UIAxes15                        matlab.ui.control.UIAxes
        SetCirclesdoNOTexitthiswindowButton  matlab.ui.control.Button
        UseCirclesButton                matlab.ui.control.Button
        Tab2                            matlab.ui.container.Tab
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes12                        matlab.ui.control.UIAxes
        CheckThatDataDropDownLabel      matlab.ui.control.Label
        CheckThatDataDropDown           matlab.ui.control.DropDown
        Tab3                            matlab.ui.container.Tab
        UIAxes16                        matlab.ui.control.UIAxes
        Graph3DDropDownLabel            matlab.ui.control.Label
        Graph3DDropDown                 matlab.ui.control.DropDown
        BackgroundDropDownLabel         matlab.ui.control.Label
        BackgroundDropDown              matlab.ui.control.DropDown
        Tab4                            matlab.ui.container.Tab
        UIAxes17                        matlab.ui.control.UIAxes
        GraphDropDownLabel              matlab.ui.control.Label
        GraphDropDown                   matlab.ui.control.DropDown
        Tab5                            matlab.ui.container.Tab
        UIAxes18                        matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        filenameEditFieldLabel          matlab.ui.control.Label
        filenameEditField               matlab.ui.control.EditField
        ThresholdNucleusEditFieldLabel  matlab.ui.control.Label
        ThresholdNucleusEditField       matlab.ui.control.NumericEditField
        R1ThresholdEditFieldLabel       matlab.ui.control.Label
        R1ThresholdEditField            matlab.ui.control.NumericEditField
        R2ThresholdEditFieldLabel       matlab.ui.control.Label
        R2ThresholdEditField            matlab.ui.control.NumericEditField
        NucleusminRangeEditFieldLabel   matlab.ui.control.Label
        NucleusminRangeEditField        matlab.ui.control.NumericEditField
        NucleusmaxRangeEditFieldLabel   matlab.ui.control.Label
        NucleusmaxRangeEditField        matlab.ui.control.NumericEditField
        RibbonminRangeEditFieldLabel    matlab.ui.control.Label
        RibbonminRangeEditField         matlab.ui.control.NumericEditField
        RibbonmaxRangeEditFieldLabel    matlab.ui.control.Label
        RibbonmaxRangeEditField         matlab.ui.control.NumericEditField
        NucleusChannelEditFieldLabel    matlab.ui.control.Label
        NucleusChannelEditField         matlab.ui.control.NumericEditField
        PresynapticChannelEditFieldLabel  matlab.ui.control.Label
        PresynapticChannelEditField     matlab.ui.control.NumericEditField
        PostsynapticChannelEditFieldLabel  matlab.ui.control.Label
        PostsynapticChannelEditField    matlab.ui.control.NumericEditField
        LoadDataButton                  matlab.ui.control.Button
        LookatSliceSpinnerLabel         matlab.ui.control.Label
        LookatSliceSpinner              matlab.ui.control.Spinner
        NucleusSensitivityEditFieldLabel  matlab.ui.control.Label
        NucleusSensitivityEditField     matlab.ui.control.NumericEditField
        Graph1sliceButton               matlab.ui.control.Button
        LastChannelEditFieldLabel       matlab.ui.control.Label
        LastChannelEditField            matlab.ui.control.NumericEditField
        NucleusMedianRangeEditFieldLabel  matlab.ui.control.Label
        NucleusMedianRangeEditField     matlab.ui.control.NumericEditField
        NucleusRadiusMaxEditFieldLabel  matlab.ui.control.Label
        NucleusRadiusMaxEditField       matlab.ui.control.EditField
        RibbonRadiusEditFieldLabel      matlab.ui.control.Label
        RibbonRadiusEditField           matlab.ui.control.EditField
        RibbonMedianRangeEditFieldLabel  matlab.ui.control.Label
        RibbonMedianRangeEditField      matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel           matlab.ui.control.Label
        EpsilonEditField                matlab.ui.control.NumericEditField
        MinimumRibbonSizeEditFieldLabel  matlab.ui.control.Label
        MinimumRibbonSizeEditField      matlab.ui.control.NumericEditField
        LookAtChannelDropDownLabel      matlab.ui.control.Label
        LookAtChannelDropDown           matlab.ui.control.DropDown
        Tab2_2                          matlab.ui.container.Tab
        FirstSliceEditFieldLabel        matlab.ui.control.Label
        FirstSliceEditField             matlab.ui.control.NumericEditField
        LastSliceEditFieldLabel         matlab.ui.control.Label
        LastSliceEditField              matlab.ui.control.NumericEditField
        xrotationEditFieldLabel         matlab.ui.control.Label
        xrotationEditField              matlab.ui.control.NumericEditField
        yrotationEditFieldLabel         matlab.ui.control.Label
        yrotationEditField              matlab.ui.control.NumericEditField
        loaddataButton                  matlab.ui.control.Button
        UpdatestartingInfoButton        matlab.ui.control.Button
        GraphSliceSpinnerLabel          matlab.ui.control.Label
        GraphSliceSpinner               matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        newCenters;
        mu;
        ribbon;
        data;
        sliceNumber;
        dataFile;
        startValue;
        stopValue;
        xRot;
        yRot;
        thresholdN;
        thresholdR1;
        thresholdR2;
        range1;
        range2;
        nSlice;
        r1Slice;
        r2Slice;
        filteredData;
        
        plainData
        voxel
        dimensions
        channel
        minMax
        allData
        S1
        S2
        P13
        P14
        P15
        P13Original
        P13BrightnessAdjusted
        
        NAssociated
        RAssociated
        
        allFiltered
        NCenters
        NRadii
        presynapticPositions
        postsynapticPositions
        
        discardedN
        discardedRPre
        discardedRPost
        
        S3
        S4
        S5
        S6
        S7
        S8
        P4
        P5
        P6
        graphPre
        graphPost
        graphNuc
        MLFigure
        
        channelArray
        Scat3D
        
    end
    
    methods (Access = private)
        
        function graphOne(app)
            app.LookAtChannelDropDown.Value
            if str2double(app.LookAtChannelDropDown.Value)=='1'
                
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingNucleus(app.plainData(:,:,app.LookatSliceSpinner.Value,app.NucleusChannelEditField.Value),app.ThresholdNucleusEditField.Value,[app.NucleusMedianRangeEditField.Value,app.NucleusMedianRangeEditField.Value], ...
                    [app.NucleusminRangeEditField.Value,app.NucleusmaxRangeEditField.Value],[str2double(app.NucleusRadiusMaxEditField.Value)-40,str2double(app.NucleusRadiusMaxEditField.Value)] ...
                    ,app.NucleusSensitivityEditField.Value,app.UIAxes13,app.UIAxes14,app.UIAxes15,app.minMax(1,1),app.minMax(1,2));
            elseif app.LookAtChannelDropDown.Value=='2'
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingRibbon(app.UIAxes13,app.UIAxes14,app.UIAxes15,app.plainData(:,:,app.LookatSliceSpinner.Value,app.PresynapticChannelEditField.Value),[app.RibbonMedianRangeEditField.Value,app.RibbonMedianRangeEditField.Value], ...
                    app.R1ThresholdEditField.Value,app.EpsilonEditField.Value,app.MinimumRibbonSizeEditField.Value,[app.RibbonminRangeEditField.Value,app.RibbonmaxRangeEditField.Value] ...
                    ,str2double(app.RibbonRadiusEditField.Value),app.minMax(2,1),app.minMax(2,2));
                
                app.P13Original=get(app.P13,'Cdata');
                app.P13BrightnessAdjusted=get(app.P13,'Cdata');
                brightnessFunc(app,app.BrightnessSlider.Value);
                showCircles(app,app.ShowCirclesButton.Value);

            elseif app.LookAtChannelDropDown.Value=='3'
                
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingRibbon(app.UIAxes13,app.UIAxes14,app.UIAxes15,app.plainData(:,:,app.LookatSliceSpinner.Value,app.PostsynapticChannelEditField.Value),[app.RibbonMedianRangeEditField.Value,app.RibbonMedianRangeEditField.Value], ...
                    app.R2ThresholdEditField.Value,app.EpsilonEditField.Value,app.MinimumRibbonSizeEditField.Value,[app.RibbonminRangeEditField.Value,app.RibbonmaxRangeEditField.Value] ...
                    ,str2double(app.RibbonRadiusEditField.Value),app.minMax(3,1),app.minMax(3,2));
                
                    app.P13Original=get(app.P13,'Cdata');
                    app.P13BrightnessAdjusted=get(app.P13,'Cdata');
                    brightnessFunc(app,app.BrightnessSlider.Value);
                    showCircles(app,app.ShowCirclesButton.Value);

            else 
                
            end
        end
        
        function contrastFunc(app,changingValue)
            J=imadjust(app.P13BrightnessAdjusted,[changingValue,1-changingValue]);
            app.P13.CData=J;
        end
        
        function brightnessFunc(app,changingValue)
            app.P13BrightnessAdjusted=min(app.P13Original+changingValue,1);
            app.P13.CData=app.P13BrightnessAdjusted;
            contrastFunc(app,app.ContrastSlider.Value);
        end
        
        function showCircles(app,value)
            markerType=["o","none"];
            app.S1.Marker=markerType(value+1);
            app.S2.Marker=markerType(value+1);
        end
        
        function graphFinal(app,slice,channel)
            
            
            [Radii,Centers,DCircles,DRadii]=findStats(app,channel,slice);
            
            
            app.channelArray=[app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value];
            
            Data=app.plainData(:,:,slice,h(channel));
            Filtered=app.filteredData(:,:,slice,h(channel));
            
            
            if size(DCircles,1)>0
                [app.S3,app.S4,app.P4,app.P5,app.P6]=graphInitial(app.UIAxes10,app.UIAxes11,app.UIAxes12,Data,Filtered,Centers,Radii);
                [app.S5,app.S6]=graphCircles(app.UIAxes10,app.UIAxes12,DCircles,DRadii,'r');
            else
                [~,~,app.P4,app.P5,app.P6]=graphInitial(app.UIAxes10,app.UIAxes11,app.UIAxes12,Data,Filtered,Centers,Radii);
                [app.S5,app.S6]=graphCircles(app.UIAxes10,app.UIAxes12,DCircles,DRadii,'r');
            end
           
            
        end
        
        function associatedChanged(app)
            set(app.NAssociated,'AlphaData',0,'FaceAlpha',0,'EdgeAlpha',0);
            for b = 1:2
                for i = 1:size(app.NAssociated,1)
                    set(app.RAssociated(b).associated(i).gobject,'AlphaData',0,'FaceAlpha',0,'EdgeAlpha',0);
                end
            end
            if app.GraphDropDown.Value==1
                set(app.NAssociated,'AlphaData',1,'FaceAlpha',1);
                for b = 1:2
                for i = 1:size(app.NAssociated,1)
                    set(app.RAssociated(b).associated(i).gobject,'AlphaData',1,'FaceAlpha',1);
                end
                end
            else
                set(app.NAssociated(app.GraphDropDown.Value-1),'AlphaData',1,'FaceAlpha',1);
                for b = 1:2
                    set(app.RAssociated(b).associated(app.GraphDropDown.Value-1).gobject,'AlphaData',1,'FaceAlpha',1);
                end
            end


        end
        
        function associatedRun(app)
            A=[];
            for i = 1:size(app.NAssociated,1)
                A=[A;sprintf('Associated set # %i',i)];
            end
            
            app.GraphDropDown.Items=["All Associated Nuclei";A];
            app.GraphDropDown.ItemsData=[1:i+1];
        end
        
        function toGraphML(app)
           
           app.MLFigure= graphMachineLearning(app.P13,app.S1);
           
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FilenameEditFieldValueChanged(app, event)
         
        end

        % Button pushed function: UpdatestartingInfoButton
        function UpdatestartingInfoButtonPushed(app, event)
           
                    app.channelArray=[app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value];


            [app.allFiltered,app.NCenters,app.NRadii,app.presynapticPositions,app.postsynapticPositions,app.discardedN,app.discardedRPre,app.discardedRPost,app.ribbon.grouped]=analyzeData( ...
                app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value,[app.ThresholdNucleusEditField.Value, ...
                app.R1ThresholdEditField.Value,app.R2ThresholdEditField.Value,.1],[app.NucleusMedianRangeEditField.Value,app.NucleusMedianRangeEditField.Value;app.RibbonMedianRangeEditField.Value,app.RibbonMedianRangeEditField.Value;app.RibbonMedianRangeEditField.Value,app.RibbonMedianRangeEditField.Value;6,6], ...
                app.FirstSliceEditField.Value,app.LastSliceEditField.Value,app.NucleusSensitivityEditField.Value,[app.NucleusminRangeEditField.Value,app.NucleusmaxRangeEditField.Value],[app.RibbonminRangeEditField.Value,app.RibbonmaxRangeEditField.Value], ...
                [str2double(app.NucleusRadiusMaxEditField.Value)-40,str2double(app.NucleusRadiusMaxEditField.Value)],app.EpsilonEditField.Value,app.MinimumRibbonSizeEditField.Value,app.voxel,app.dimensions,app.plainData);


        end

        % Value changed function: xrotationEditField
        function xrotationEditFieldValueChanged(app, event)


            value = app.xrotationEditField.Value;
            app.xRot=value;
        end

        % Value changed function: yrotationEditField
        function yrotationEditFieldValueChanged(app, event)
            value = app.yrotationEditField.Value;
            app.yRot=value;
        end

        % Value changed function: ThresholdNucleusEditField
        function ThresholdNucleusEditFieldValueChanged(app, event)
            value = app.ThresholdNucleusEditField.Value;
            app.thresholdN=value;
        end

        % Value changed function: R1ThresholdEditField
        function R1ThresholdEditFieldValueChanged(app, event)
            value = app.R1ThresholdEditField.Value;
            app.thresholdR1=value;
        end

        % Value changed function: R2ThresholdEditField
        function R2ThresholdEditFieldValueChanged(app, event)
            value = app.R2ThresholdEditField.Value;
            app.thresholdR2=value;
        end

        % Value changed function: FirstSliceEditField
        function FirstSliceEditFieldValueChanged(app, event)
            value = app.FirstSliceEditField.Value;
            app.startValue=value;
        end

        % Value changed function: LastSliceEditField
        function LastSliceEditFieldValueChanged(app, event)
            value = app.LastSliceEditField.Value;
            app.stopValue=value;
        end

        % Value changed function: RibbonminRangeEditField
        function RibbonminRangeEditFieldValueChanged(app, event)
            value = app.RibbonminRangeEditField.Value;
            app.range2(1)=value;
        end

        % Value changed function: NucleusminRangeEditField
        function NucleusminRangeEditFieldValueChanged(app, event)
            value = app.NucleusminRangeEditField.Value;
            app.range1(1)=value;
        end

        % Value changed function: NucleusmaxRangeEditField
        function NucleusmaxRangeEditFieldValueChanged(app, event)
            value = app.NucleusmaxRangeEditField.Value;
            app.range1(2)=value;
        end

        % Value changed function: RibbonmaxRangeEditField
        function RibbonmaxRangeEditFieldValueChanged(app, event)
            value = app.RibbonmaxRangeEditField.Value;
            app.range2(2)=value;
        end

        % Value changed function: PostsynapticChannelEditField
        function PostsynapticChannelEditFieldValueChanged(app, event)
            value = app.PostsynapticChannelEditField.Value;
            app.r1Slice=value;
        end

        % Value changed function: NucleusChannelEditField
        function NucleusChannelEditFieldValueChanged(app, event)
            value = app.NucleusChannelEditField.Value;
            app.nSlice=value;
        end

        % Value changed function: PresynapticChannelEditField
        function PresynapticChannelEditFieldValueChanged(app, event)
            value = app.PresynapticChannelEditField.Value;
            app.r2Slice=value;
        end

        % Value changed function: filenameEditField
        function filenameEditFieldValueChanged(app, event)
            
            value = app.filenameEditField.Value;
            app.dataFile=value;
        end

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            %load ('loadData.m', 'newCenters', 'mu', 'ribbon', 'data');
            app.NCenters=evalin('base','newCenters');
            app.NRadii=evalin('base','mu');
            app.plainData=evalin('base','plainData');
            app.filteredData=evalin('base','allFiltered');
            %dropDownMatrix=[1:round((app.LastSliceEditField.Value+1-app.FirstSliceEditField.Value)/4)];
%             app.presynapticPositions=evalin('base','sendPre');
%             app.postsynapticPositions=evalin('base','sendPost');
            app.voxel=evalin('base','voxel');
            app.dimensions=evalin('base','dimensions');
            app.minMax=evalin('base','minMax');
            app.presynapticPositions=evalin('base','sendPre');
            app.postsynapticPositions=evalin('base','sendPost');
            app.discardedN=evalin('base','discardedN');
            app.discardedRPre=evalin('base','discardedRPre');
            app.discardedRPost=evalin('base','discardedRPost');
            app.ribbon=evalin('base','ribbon');
            app.newCenters=evalin('base','newCenters');
            app.mu=evalin('base','mu');


            [app.NAssociated,app.RAssociated]=associateNR(app.ribbon,app.newCenters,app.mu,app.voxel,app.UIAxes17,str2double(app.RibbonRadiusEditField.Value));
            associatedRun(app);
            [R1,R2]=representNAndR(app.NAssociated,app.RAssociated,app.UIAxes18);
            
            app.channelArray=[app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value];

            
        end

        % Callback function
        function GraphSliceDropDownValueChanged(app, event)
            value = app.GraphSliceDropDown.Value;
             
        end

        % Callback function
        function GraphSliceEditFieldValueChanged(app, event)
            
        end

        % Value changed function: GraphSliceSpinner
        function GraphSliceSpinnerValueChanged(app, event)
            value = app.GraphSliceSpinner.Value;
            channel=str2double(app.LookAtChannelDropDown.Value);
            graphFinal(app,value,channel);
%             graphRibbon(app.UIAxes4,app.UIAxes5,app.UIAxes6,app.presynapticPositions(value).points,app.data(:,:,round(value),app.PresynapticChannelEditField.Value),app.filteredData(:,:,round(value),app.PresynapticChannelEditField.Value));
%                         graphRibbon(app.UIAxes7,app.UIAxes8,app.UIAxes9,app.postsynapticPositions(value).points,app.data(:,:,round(value),app.PostsynapticChannelEditField.Value),app.filteredData(:,:,round(value),app.PostsynapticChannelEditField.Value));
% 
% %             
% %             graphDetected(app.UIAxes7,app.UIAxes8,app.UIAxes9,app.newCenters,app.mu,app.ribbon,app.data(:,:,double(round(value)),app.PresynapticChannelEditField.Value),round(value), app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value, ...
% %             app.filteredData(:,:,round(value),app.PresynapticChannelEditField.Value),[app.NucleusminRangeEditField.Value,app.NucleusmaxRangeEditField.Value],[app.RibbonminRangeEditField.Value,app.RibbonmaxRangeEditField.Value],app.FirstSliceEditField.Value,app.presynapticPositions,app.postsynapticPositions);
%             
%             graphNuclei(app.UIAxes10,app.UIAxes11,app.UIAxes12,app.newCenters,app.mu,app.data(:,:,round(value),app.NucleusChannelEditField.Value),round(value),app.filteredData(:,:,round(value)));


        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [app.voxel,app.dimensions,app.minMax,app.plainData]=initialize(app.filenameEditField.Value,app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value,app.LastChannelEditField.Value);
            
        end

        % Callback function
        function LookatChannelDropDownValueChanged(app, event)
            
        end

        % Button pushed function: Graph1sliceButton
        function Graph1sliceButtonPushed(app, event)

            graphOne(app);
            
            
        end

        % Value changed function: LookatSliceSpinner
        function LookatSliceSpinnerValueChanged(app, event)
            value = app.LookatSliceSpinner.Value;
            graphOne(app);
        end

        % Value changed function: ShowCirclesButton
        function ShowCirclesButtonValueChanged(app, event)
            value = app.ShowCirclesButton.Value;            
            showCircles(app,value);
            
        end

        % Value changing function: ContrastSlider
        function ContrastSliderValueChanging(app, event)
             changingValue = event.Value;
             contrastFunc(app,changingValue);

        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            brightnessFunc(app,changingValue);
        end

        % Callback function
        function Graph3DpixelsButtonPushed(app, event)
            [app.graphPre,app.graphPost,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,str2double(app.RibbonRadiusEditField.Value));
        end

        % Value changed function: Graph3DDropDown
        function Graph3DDropDownValueChanged(app, event)
            value = app.Graph3DDropDown.Value;
            if value=='Pixels'
                [app.graphPre,app.graphPost,app.graphNuc,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,0,str2double(app.RibbonRadiusEditField.Value));
            elseif value=='Scaled'
                [app.graphPre,app.graphPost,app.graphNuc,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,1,str2double(app.RibbonRadiusEditField.Value));
            end
        end

        % Callback function
        function ButtonPushed(app, event)
            app.GraphDropDown.Items=["All Associated Nuclei","another"];
            app.GraphDropDown.ItemsData=[1,2];
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
            value = app.GraphDropDown.Value;
            associatedChanged(app);
        end

        % Button pushed function: 
        % SetCirclesdoNOTexitthiswindowButton
        function SetCirclesdoNOTexitthiswindowButtonPushed(app, event)
            toGraphML(app);
            
        end

        % Button pushed function: UseCirclesButton
        function UseCirclesButtonPushed(app, event)
            if app.LookAtChannelDropDown.Value=='1'
                settingsArrayN=bestFitSettingsN(app.MLFigure,app.plainData(:,:,app.LookatSliceSpinner.Value,str2double(app.LookAtChannelDropDown.Value)),app.ThresholdNucleusEditField.Value,[app.NucleusMedianRangeEditField.Value,app.NucleusMedianRangeEditField.Value], ...
                    [app.NucleusminRangeEditField.Value,app.NucleusmaxRangeEditField.Value],[str2double(app.NucleusRadiusMaxEditField.Value)-40,str2double(app.NucleusRadiusMaxEditField.Value)] ...
                    ,app.NucleusSensitivityEditField.Value,app.minMax(2,1),app.minMax(2,2));
            elseif app.LookAtChannelDropDown.Value=='2'
                settingsArrayR1=bestFitSettingsR(app.MLFigure,app.plainData(:,:,app.LookatSliceSpinner.Value,str2double(app.LookAtChannelDropDown.Value)),[app.RibbonMedianRangeEditField.Value,app.RibbonMedianRangeEditField.Value], ...
                    app.R1ThresholdEditField.Value,app.EpsilonEditField.Value,app.MinimumRibbonSizeEditField.Value,[app.RibbonminRangeEditField.Value,app.RibbonmaxRangeEditField.Value] ...
                    ,str2double(app.RibbonRadiusEditField.Value),app.minMax(3,1),app.minMax(3,2))
            elseif app.LookAtChannelDropDown.Value=='3'
                
            end
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            value = app.BackgroundDropDown.Value;
            f=figure;
            a=axes(f);
            
            [A,B,C,~,~]=graph3D(a,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,0,str2double(app.RibbonRadiusEditField.Value));
            
            set(C(:,1),'FaceAlpha',0);
            set(A(:,1),'FaceAlpha',.4);
            set(B(:,1),'FaceAlpha',.4);
            
            m=[app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value];
            g=graphFinal3D(a,app.plainData(:,:,:,m(str2double(value))),app.FirstSliceEditField.Value,app.LastSliceEditField.Value,1);
            set(f,'WindowScrollWheelFcn',{@getThatScroll,g});
            

        end

        % Value changed function: CheckThatDataDropDown
        function CheckThatDataDropDownValueChanged(app, event)
            value = app.CheckThatDataDropDown.Value;
            
            f=figure;
            a=axes(f);


            m=[app.NucleusChannelEditField.Value,app.PresynapticChannelEditField.Value,app.PostsynapticChannelEditField.Value];
            g=graphFinal3D(a,app.plainData(:,:,:,m(str2double(value))),app.FirstSliceEditField.Value,app.LastSliceEditField.Value,0);


            app.Scat3D=gobjects();
            view(a,0,90)
            for slice=0:size(g,2)-1
                [Radii,Centers,~,~]=findStats(app,str2double(app.CheckThatDataDropDown.Value),slice+app.FirstSliceEditField.Value);

                if size(Centers,1)>0
                
                    [app.Scat3D(slice+app.FirstSliceEditField.Value,1)]=graphCircles3D(a,Centers,Radii,slice,'r');
                end
            end
            
            set(f,'WindowScrollWheelFcn',{@getThatScroll,g,app});
            set(f,'WindowButtonDownFcn',{@addRibbon,g,app});
            
            mini=find(isgraphics(app.Scat3D(:,1)),1);
            maxi=size(isgraphics(app.Scat3D(:,1)),1);
            set(app.Scat3D(mini:maxi,1),'ButtonDownFcn',{@removeRibbon,app,g});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.ShowCirclesButton = uibutton(app.Tab, 'state');
            app.ShowCirclesButton.ValueChangedFcn = createCallbackFcn(app, @ShowCirclesButtonValueChanged, true);
            app.ShowCirclesButton.Text = 'Show Circles';
            app.ContrastSliderLabel = uilabel(app.Tab);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.Tab);
            app.ContrastSlider.Limits = [0 0.4];
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @ContrastSliderValueChanging, true);
            app.BrightnessSliderLabel = uilabel(app.Tab);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.Tab);
            app.BrightnessSlider.Limits = [0 1];
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.UIAxes13 = uiaxes(app.Tab);
            title(app.UIAxes13, '')
            xlabel(app.UIAxes13, '')
            ylabel(app.UIAxes13, '')
            app.UIAxes13.XTick = [];
            app.UIAxes13.YTick = [];
            app.UIAxes14 = uiaxes(app.Tab);
            title(app.UIAxes14, '')
            xlabel(app.UIAxes14, '')
            ylabel(app.UIAxes14, '')
            app.UIAxes14.XTick = [];
            app.UIAxes14.YTick = [];
            app.UIAxes15 = uiaxes(app.Tab);
            title(app.UIAxes15, '')
            xlabel(app.UIAxes15, '')
            ylabel(app.UIAxes15, '')
            app.UIAxes15.XTick = [];
            app.UIAxes15.YTick = [];
            app.SetCirclesdoNOTexitthiswindowButton = uibutton(app.Tab, 'push');
            app.SetCirclesdoNOTexitthiswindowButton.ButtonPushedFcn = createCallbackFcn(app, @SetCirclesdoNOTexitthiswindowButtonPushed, true);
            app.SetCirclesdoNOTexitthiswindowButton.Text = 'Set Circles(do NOT exit this window)';
            app.UseCirclesButton = uibutton(app.Tab, 'push');
            app.UseCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @UseCirclesButtonPushed, true);
            app.UseCirclesButton.Text = 'Use Circles';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes10 = uiaxes(app.Tab2);
            title(app.UIAxes10, '')
            xlabel(app.UIAxes10, '')
            ylabel(app.UIAxes10, '')
            app.UIAxes10.XTick = [];
            app.UIAxes10.YTick = [];
            app.UIAxes11 = uiaxes(app.Tab2);
            title(app.UIAxes11, '')
            xlabel(app.UIAxes11, '')
            ylabel(app.UIAxes11, '')
            app.UIAxes11.XTick = [];
            app.UIAxes11.YTick = [];
            app.UIAxes12 = uiaxes(app.Tab2);
            title(app.UIAxes12, '')
            xlabel(app.UIAxes12, '')
            ylabel(app.UIAxes12, '')
            app.UIAxes12.XTick = [];
            app.UIAxes12.YTick = [];
            app.CheckThatDataDropDownLabel = uilabel(app.Tab2);
            app.CheckThatDataDropDownLabel.Text = 'Check That Data!';
            app.CheckThatDataDropDown = uidropdown(app.Tab2);
            app.CheckThatDataDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic'};
            app.CheckThatDataDropDown.ItemsData = {'1', '2', '3'};
            app.CheckThatDataDropDown.ValueChangedFcn = createCallbackFcn(app, @CheckThatDataDropDownValueChanged, true);
            app.CheckThatDataDropDown.Value = '1';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes16 = uiaxes(app.Tab3);
            title(app.UIAxes16, 'Title')
            xlabel(app.UIAxes16, 'X')
            ylabel(app.UIAxes16, 'Y')
            app.Graph3DDropDownLabel = uilabel(app.Tab3);
            app.Graph3DDropDownLabel.Text = 'Graph 3D';
            app.Graph3DDropDown = uidropdown(app.Tab3);
            app.Graph3DDropDown.Items = {'Pixels', 'Scaled', ''};
            app.Graph3DDropDown.ValueChangedFcn = createCallbackFcn(app, @Graph3DDropDownValueChanged, true);
            app.Graph3DDropDown.Value = 'Scaled';
            app.BackgroundDropDownLabel = uilabel(app.Tab3);
            app.BackgroundDropDownLabel.Text = 'Background:';
            app.BackgroundDropDown = uidropdown(app.Tab3);
            app.BackgroundDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic'};
            app.BackgroundDropDown.ItemsData = {'1', '2', '3'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Value = '1';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.UIAxes17 = uiaxes(app.Tab4);
            title(app.UIAxes17, 'Title')
            xlabel(app.UIAxes17, 'X')
            ylabel(app.UIAxes17, 'Y')
            app.GraphDropDownLabel = uilabel(app.Tab4);
            app.GraphDropDownLabel.Text = 'Graph:';
            app.GraphDropDown = uidropdown(app.Tab4);
            app.GraphDropDown.Items = {'All Associated Nuclei'};
            app.GraphDropDown.ItemsData = {'1'};
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.GraphDropDown.Value = '1';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.UIAxes18 = uiaxes(app.Tab5);
            title(app.UIAxes18, 'Title')
            xlabel(app.UIAxes18, 'X')
            ylabel(app.UIAxes18, 'Y')
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'Tab';
            app.filenameEditFieldLabel = uilabel(app.Tab_2);
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.Tab_2, 'text');
            app.filenameEditField.ValueChangedFcn = createCallbackFcn(app, @filenameEditFieldValueChanged, true);
            app.filenameEditField.Value = 'IB09MidControlLeft2.czi';
            app.ThresholdNucleusEditFieldLabel = uilabel(app.Tab_2);
            app.ThresholdNucleusEditFieldLabel.Text = 'Threshold(Nucleus)';
            app.ThresholdNucleusEditField = uieditfield(app.Tab_2, 'numeric');
            app.ThresholdNucleusEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdNucleusEditFieldValueChanged, true);
            app.ThresholdNucleusEditField.Value = 0.08;
            app.R1ThresholdEditFieldLabel = uilabel(app.Tab_2);
            app.R1ThresholdEditFieldLabel.Text = 'R1Threshold';
            app.R1ThresholdEditField = uieditfield(app.Tab_2, 'numeric');
            app.R1ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @R1ThresholdEditFieldValueChanged, true);
            app.R1ThresholdEditField.Value = 0.06;
            app.R2ThresholdEditFieldLabel = uilabel(app.Tab_2);
            app.R2ThresholdEditFieldLabel.Text = 'R2 Threshold';
            app.R2ThresholdEditField = uieditfield(app.Tab_2, 'numeric');
            app.R2ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @R2ThresholdEditFieldValueChanged, true);
            app.R2ThresholdEditField.Value = 0.07;
            app.NucleusminRangeEditFieldLabel = uilabel(app.Tab_2);
            app.NucleusminRangeEditFieldLabel.Text = 'Nucleus min. Range';
            app.NucleusminRangeEditField = uieditfield(app.Tab_2, 'numeric');
            app.NucleusminRangeEditField.ValueChangedFcn = createCallbackFcn(app, @NucleusminRangeEditFieldValueChanged, true);
            app.NucleusminRangeEditField.Value = 1200;
            app.NucleusmaxRangeEditFieldLabel = uilabel(app.Tab_2);
            app.NucleusmaxRangeEditFieldLabel.Text = 'Nucleus max. Range';
            app.NucleusmaxRangeEditField = uieditfield(app.Tab_2, 'numeric');
            app.NucleusmaxRangeEditField.ValueChangedFcn = createCallbackFcn(app, @NucleusmaxRangeEditFieldValueChanged, true);
            app.NucleusmaxRangeEditField.Value = 1500;
            app.RibbonminRangeEditFieldLabel = uilabel(app.Tab_2);
            app.RibbonminRangeEditFieldLabel.Text = 'Ribbon min. Range';
            app.RibbonminRangeEditField = uieditfield(app.Tab_2, 'numeric');
            app.RibbonminRangeEditField.ValueChangedFcn = createCallbackFcn(app, @RibbonminRangeEditFieldValueChanged, true);
            app.RibbonminRangeEditField.Value = 1200;
            app.RibbonmaxRangeEditFieldLabel = uilabel(app.Tab_2);
            app.RibbonmaxRangeEditFieldLabel.Text = 'Ribbon max. Range';
            app.RibbonmaxRangeEditField = uieditfield(app.Tab_2, 'numeric');
            app.RibbonmaxRangeEditField.ValueChangedFcn = createCallbackFcn(app, @RibbonmaxRangeEditFieldValueChanged, true);
            app.RibbonmaxRangeEditField.Value = 1700;
            app.NucleusChannelEditFieldLabel = uilabel(app.Tab_2);
            app.NucleusChannelEditFieldLabel.Text = 'Nucleus Channel';
            app.NucleusChannelEditField = uieditfield(app.Tab_2, 'numeric');
            app.NucleusChannelEditField.ValueChangedFcn = createCallbackFcn(app, @NucleusChannelEditFieldValueChanged, true);
            app.NucleusChannelEditField.Value = 1;
            app.PresynapticChannelEditFieldLabel = uilabel(app.Tab_2);
            app.PresynapticChannelEditFieldLabel.Text = 'Presynaptic Channel';
            app.PresynapticChannelEditField = uieditfield(app.Tab_2, 'numeric');
            app.PresynapticChannelEditField.ValueChangedFcn = createCallbackFcn(app, @PresynapticChannelEditFieldValueChanged, true);
            app.PresynapticChannelEditField.Value = 3;
            app.PostsynapticChannelEditFieldLabel = uilabel(app.Tab_2);
            app.PostsynapticChannelEditFieldLabel.Text = 'Postsynaptic Channel';
            app.PostsynapticChannelEditField = uieditfield(app.Tab_2, 'numeric');
            app.PostsynapticChannelEditField.ValueChangedFcn = createCallbackFcn(app, @PostsynapticChannelEditFieldValueChanged, true);
            app.PostsynapticChannelEditField.Value = 2;
            app.LoadDataButton = uibutton(app.Tab_2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LookatSliceSpinnerLabel = uilabel(app.Tab_2);
            app.LookatSliceSpinnerLabel.Text = 'Look at Slice:';
            app.LookatSliceSpinner = uispinner(app.Tab_2);
            app.LookatSliceSpinner.ValueChangedFcn = createCallbackFcn(app, @LookatSliceSpinnerValueChanged, true);
            app.LookatSliceSpinner.Value = 10;
            app.NucleusSensitivityEditFieldLabel = uilabel(app.Tab_2);
            app.NucleusSensitivityEditFieldLabel.Text = 'Nucleus Sensitivity';
            app.NucleusSensitivityEditField = uieditfield(app.Tab_2, 'numeric');
            app.NucleusSensitivityEditField.Value = 0.972;
            app.Graph1sliceButton = uibutton(app.Tab_2, 'push');
            app.Graph1sliceButton.ButtonPushedFcn = createCallbackFcn(app, @Graph1sliceButtonPushed, true);
            app.Graph1sliceButton.Text = 'Graph 1 slice';
            app.LastChannelEditFieldLabel = uilabel(app.Tab_2);
            app.LastChannelEditFieldLabel.Text = 'Last Channel';
            app.LastChannelEditField = uieditfield(app.Tab_2, 'numeric');
            app.LastChannelEditField.Value = 4;
            app.NucleusMedianRangeEditFieldLabel = uilabel(app.Tab_2);
            app.NucleusMedianRangeEditFieldLabel.Text = 'Nucleus Median Range';
            app.NucleusMedianRangeEditField = uieditfield(app.Tab_2, 'numeric');
            app.NucleusMedianRangeEditField.Value = 6;
            app.NucleusRadiusMaxEditFieldLabel = uilabel(app.Tab_2);
            app.NucleusRadiusMaxEditFieldLabel.Text = 'Nucleus Radius Max';
            app.NucleusRadiusMaxEditField = uieditfield(app.Tab_2, 'text');
            app.NucleusRadiusMaxEditField.Value = '100';
            app.RibbonRadiusEditFieldLabel = uilabel(app.Tab_2);
            app.RibbonRadiusEditFieldLabel.Text = 'Ribbon Radius';
            app.RibbonRadiusEditField = uieditfield(app.Tab_2, 'text');
            app.RibbonRadiusEditField.Value = '15';
            app.RibbonMedianRangeEditFieldLabel = uilabel(app.Tab_2);
            app.RibbonMedianRangeEditFieldLabel.Text = 'Ribbon Median Range';
            app.RibbonMedianRangeEditField = uieditfield(app.Tab_2, 'numeric');
            app.RibbonMedianRangeEditField.Value = 5;
            app.EpsilonEditFieldLabel = uilabel(app.Tab_2);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.Tab_2, 'numeric');
            app.EpsilonEditField.Value = 10;
            app.MinimumRibbonSizeEditFieldLabel = uilabel(app.Tab_2);
            app.MinimumRibbonSizeEditFieldLabel.Text = 'Minimum Ribbon Size';
            app.MinimumRibbonSizeEditField = uieditfield(app.Tab_2, 'numeric');
            app.MinimumRibbonSizeEditField.Value = 5;
            app.LookAtChannelDropDownLabel = uilabel(app.Tab_2);
            app.LookAtChannelDropDownLabel.Text = 'Look At Channel';
            app.LookAtChannelDropDown = uidropdown(app.Tab_2);
            app.LookAtChannelDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic', 'Extra'};
            app.LookAtChannelDropDown.ItemsData = {'1', '2', '3', '4'};
            app.LookAtChannelDropDown.Value = '2';
            app.Tab2_2 = uitab(app.TabGroup2);
            app.Tab2_2.Title = 'Tab2';
            app.FirstSliceEditFieldLabel = uilabel(app.Tab2_2);
            app.FirstSliceEditFieldLabel.Text = 'First Slice';
            app.FirstSliceEditField = uieditfield(app.Tab2_2, 'numeric');
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditFieldLabel = uilabel(app.Tab2_2);
            app.LastSliceEditFieldLabel.Text = 'Last Slice';
            app.LastSliceEditField = uieditfield(app.Tab2_2, 'numeric');
            app.LastSliceEditField.ValueChangedFcn = createCallbackFcn(app, @LastSliceEditFieldValueChanged, true);
            app.LastSliceEditField.Value = 30;
            app.xrotationEditFieldLabel = uilabel(app.Tab2_2);
            app.xrotationEditFieldLabel.Text = 'x rotation';
            app.xrotationEditField = uieditfield(app.Tab2_2, 'numeric');
            app.xrotationEditField.ValueChangedFcn = createCallbackFcn(app, @xrotationEditFieldValueChanged, true);
            app.yrotationEditFieldLabel = uilabel(app.Tab2_2);
            app.yrotationEditFieldLabel.Text = 'y rotation';
            app.yrotationEditField = uieditfield(app.Tab2_2, 'numeric');
            app.yrotationEditField.ValueChangedFcn = createCallbackFcn(app, @yrotationEditFieldValueChanged, true);
            app.loaddataButton = uibutton(app.Tab2_2, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.UpdatestartingInfoButton = uibutton(app.Tab2_2, 'push');
            app.UpdatestartingInfoButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatestartingInfoButtonPushed, true);
            app.UpdatestartingInfoButton.Text = 'Update starting Info.';
            app.GraphSliceSpinnerLabel = uilabel(app.Tab2_2);
            app.GraphSliceSpinnerLabel.Text = 'Graph Slice:';
            app.GraphSliceSpinner = uispinner(app.Tab2_2);
            app.GraphSliceSpinner.ValueChangedFcn = createCallbackFcn(app, @GraphSliceSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end