classdef ribbonAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PreliminaryViewingTab         matlab.ui.container.Tab
        ShowCirclesButton             matlab.ui.control.StateButton
        ContrastSliderLabel           matlab.ui.control.Label
        ContrastSlider                matlab.ui.control.Slider
        BrightnessSliderLabel         matlab.ui.control.Label
        BrightnessSlider              matlab.ui.control.Slider
        UIAxes13                      matlab.ui.control.UIAxes
        UIAxes14                      matlab.ui.control.UIAxes
        UIAxes15                      matlab.ui.control.UIAxes
        SetCirclesdoNOTexitthiswindowButton  matlab.ui.control.Button
        UseCirclesButton              matlab.ui.control.Button
        AfterAnalysisTab              matlab.ui.container.Tab
        UIAxes10                      matlab.ui.control.UIAxes
        UIAxes11                      matlab.ui.control.UIAxes
        UIAxes12                      matlab.ui.control.UIAxes
        CheckThatDataDropDownLabel    matlab.ui.control.Label
        CheckThatDataDropDown         matlab.ui.control.DropDown
        DGraphTab                     matlab.ui.container.Tab
        UIAxes16                      matlab.ui.control.UIAxes
        Graph3DDropDownLabel          matlab.ui.control.Label
        Graph3DDropDown               matlab.ui.control.DropDown
        BackgroundDropDownLabel       matlab.ui.control.Label
        BackgroundDropDown            matlab.ui.control.DropDown
        NoOverlapTab                  matlab.ui.container.Tab
        UIAxes17                      matlab.ui.control.UIAxes
        GraphDropDownLabel            matlab.ui.control.Label
        GraphDropDown                 matlab.ui.control.DropDown
        UpdateRibbonsButton           matlab.ui.control.Button
        RepresentativeTab             matlab.ui.container.Tab
        UIAxes18                      matlab.ui.control.UIAxes
        TabGroup2                     matlab.ui.container.TabGroup
        SettingsTab                   matlab.ui.container.Tab
        FileNameEditFieldLabel        matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        ThresholdNEditFieldLabel      matlab.ui.control.Label
        ThresholdNEditField           matlab.ui.control.NumericEditField
        ThresholdPreEditFieldLabel    matlab.ui.control.Label
        ThresholdPreEditField         matlab.ui.control.NumericEditField
        ThresholdpostEditFieldLabel   matlab.ui.control.Label
        ThresholdpostEditField        matlab.ui.control.NumericEditField
        NminRangeEditFieldLabel       matlab.ui.control.Label
        NminRangeEditField            matlab.ui.control.NumericEditField
        NmaxRangeEditFieldLabel       matlab.ui.control.Label
        NmaxRangeEditField            matlab.ui.control.NumericEditField
        RminRangeEditFieldLabel       matlab.ui.control.Label
        RminRangeEditField            matlab.ui.control.NumericEditField
        RmaxRangeEditFieldLabel       matlab.ui.control.Label
        RmaxRangeEditField            matlab.ui.control.NumericEditField
        NucleusChEditFieldLabel       matlab.ui.control.Label
        NucleusChEditField            matlab.ui.control.NumericEditField
        PresynapticChEditFieldLabel   matlab.ui.control.Label
        PresynapticChEditField        matlab.ui.control.NumericEditField
        PostsynapticChEditFieldLabel  matlab.ui.control.Label
        PostsynapticChEditField       matlab.ui.control.NumericEditField
        LoadDataButton                matlab.ui.control.Button
        SliceSpinnerLabel             matlab.ui.control.Label
        SliceSpinner                  matlab.ui.control.Spinner
        SensitivityNEditFieldLabel    matlab.ui.control.Label
        SensitivityNEditField         matlab.ui.control.NumericEditField
        Graph1sliceButton             matlab.ui.control.Button
        LastChEditFieldLabel          matlab.ui.control.Label
        LastChEditField               matlab.ui.control.NumericEditField
        NMedRangeEditFieldLabel       matlab.ui.control.Label
        NMedRangeEditField            matlab.ui.control.NumericEditField
        NRadiusMaxEditFieldLabel      matlab.ui.control.Label
        NRadiusMaxEditField           matlab.ui.control.EditField
        RRadiusEditFieldLabel         matlab.ui.control.Label
        RRadiusEditField              matlab.ui.control.EditField
        RMedRangeEditFieldLabel       matlab.ui.control.Label
        RMedRangeEditField            matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel         matlab.ui.control.Label
        EpsilonEditField              matlab.ui.control.NumericEditField
        MinRSizeEditFieldLabel        matlab.ui.control.Label
        MinRSizeEditField             matlab.ui.control.NumericEditField
        OrganDropDownLabel            matlab.ui.control.Label
        OrganDropDown                 matlab.ui.control.DropDown
        NStartValueEditFieldLabel     matlab.ui.control.Label
        NStartValueEditField          matlab.ui.control.NumericEditField
        NStopValueEditFieldLabel      matlab.ui.control.Label
        NStopValueEditField           matlab.ui.control.NumericEditField
        RStartValueEditFieldLabel     matlab.ui.control.Label
        RStartValueEditField          matlab.ui.control.NumericEditField
        RStopValueEditFieldLabel      matlab.ui.control.Label
        RStopValueEditField           matlab.ui.control.NumericEditField
        MoreSettingsTab               matlab.ui.container.Tab
        xrotationEditFieldLabel       matlab.ui.control.Label
        xrotationEditField            matlab.ui.control.NumericEditField
        yrotationEditFieldLabel       matlab.ui.control.Label
        yrotationEditField            matlab.ui.control.NumericEditField
        loaddatafromworkspaceButton   matlab.ui.control.Button
        UpdatestartingInfoButton      matlab.ui.control.Button
        GraphSliceSpinnerLabel        matlab.ui.control.Label
        GraphSliceSpinner             matlab.ui.control.Spinner
        SaveToEditFieldLabel          matlab.ui.control.Label
        SaveToEditField               matlab.ui.control.EditField
        SaveButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        newCenters;
        mu;
        ribbon;
        data;
        sliceNumber;
        dataFile;
        startValue;
        stopValue;
        xRot;
        yRot;
        thresholdN;
        thresholdR1;
        thresholdR2;
        range1;
        range2;
        nSlice;
        r1Slice;
        r2Slice;
        filteredData;
        
        plainData
        voxel
        dimensions
        channel
        minMax
        allData
        S1
        S2
        P13
        P14
        P15
        P13Original
        P13BrightnessAdjusted
        
        NAssociated
        RAssociated
        
        allFiltered
        NCenters
        NRadii
        presynapticPositions
        postsynapticPositions
        
        discardedN
        discardedRPre
        discardedRPost
        
        S3
        S4
        S5
        S6
        S7
        S8
        P4
        P5
        P6
        graphPre
        graphPost
        graphNuc
        MLFigure
        
        channelArray
        Scat3D
        
        
        R1Scat
        R2Scat


    end
    
    methods (Access = private)
        %This is to graph on the first tab
        function graphOne(app)
            if app.OrganDropDown.Value=='1'
                %Find the locations of nuclei/ribbons
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingNucleus(app.plainData(:,:,app.SliceSpinner.Value,app.NucleusChEditField.Value),app.ThresholdNEditField.Value,[app.NMedRangeEditField.Value,app.NMedRangeEditField.Value], ...
                    [app.NminRangeEditField.Value,app.NmaxRangeEditField.Value],[str2double(app.NRadiusMaxEditField.Value)-40,str2double(app.NRadiusMaxEditField.Value)] ...
                    ,app.SensitivityNEditField.Value,app.UIAxes13,app.UIAxes14,app.UIAxes15,app.minMax(1,1),app.minMax(1,2),app.NminRangeEditField.Value,app.NmaxRangeEditField.Value);
                
                %save the original data
                app.P13Original=get(app.P13,'Cdata');
                %Use the brightness adjustment function and contrast to
                %make data easier to see
                app.P13BrightnessAdjusted=get(app.P13,'Cdata');
                [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,app.BrightnessSlider.Value,app.P13Original);
                showCircles(app,app.ShowCirclesButton.Value);
                
            elseif app.OrganDropDown.Value=='2'
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingRibbon(app.UIAxes13,app.UIAxes14,app.UIAxes15,app.plainData(:,:,app.SliceSpinner.Value,app.PresynapticChEditField.Value),[app.RMedRangeEditField.Value,app.RMedRangeEditField.Value], ...
                    app.ThresholdPreEditField.Value,app.EpsilonEditField.Value,app.MinRSizeEditField.Value,[app.RminRangeEditField.Value,app.RmaxRangeEditField.Value] ...
                    ,str2double(app.RRadiusEditField.Value),app.minMax(2,1),app.minMax(2,2));
                
                app.P13Original=get(app.P13,'Cdata');
                app.P13BrightnessAdjusted=get(app.P13,'Cdata');
                
                [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,app.BrightnessSlider.Value,app.P13Original);
                showCircles(app,app.ShowCirclesButton.Value);

            elseif app.OrganDropDown.Value=='3'
                
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingRibbon(app.UIAxes13,app.UIAxes14,app.UIAxes15,app.plainData(:,:,app.SliceSpinner.Value,app.PostsynapticChEditField.Value),[app.RMedRangeEditField.Value,app.RMedRangeEditField.Value], ...
                    app.ThresholdpostEditField.Value,app.EpsilonEditField.Value,app.MinRSizeEditField.Value,[app.RminRangeEditField.Value,app.RmaxRangeEditField.Value] ...
                    ,str2double(app.RRadiusEditField.Value),app.minMax(3,1),app.minMax(3,2));
                
                    app.P13Original=get(app.P13,'Cdata');
                app.P13BrightnessAdjusted=get(app.P13,'Cdata');
                
                [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,app.BrightnessSlider.Value,app.P13Original);
                showCircles(app,app.ShowCirclesButton.Value);

            else 
                
            end
        end
        
        function final=contrastFunc(app,changingValue,brightnessAdjusted)
            final=imadjust(brightnessAdjusted,[changingValue,1-changingValue]);
            
        end
        
        function [brightnessAdjusted,finalData]=brightnessFunc(app,changingValue,original)
            brightnessAdjusted=min(original+changingValue,1);
            %app.P13.CData=app.P13BrightnessAdjusted;
            finalData=contrastFunc(app,app.ContrastSlider.Value,brightnessAdjusted);
        end
        
        function showCircles(app,value)
            
            if isgraphics(app.S1,1)
                markerType=["o","none"];
                app.S1.Marker=markerType(value+1);
                app.S2.Marker=markerType(value+1);
            end
        end
        
        function graphFinal(app,slice,channel)
            
            
            [Radii,Centers,DCircles,DRadii]=findStats(app,channel,slice);
            
            
            app.channelArray=[app.NucleusChEditField.Value,app.PresynapticChEditField.Value,app.PostsynapticChEditField.Value];
            
            [~,Data]=brightnessFunc(app,app.BrightnessSlider.Value,app.plainData(:,:,slice,app.channelArray(channel))/(max(app.plainData(:,:,slice,app.channelArray(channel)),[],'all')));
            Filtered=app.filteredData(:,:,slice,app.channelArray(channel));
            
            
            if size(DCircles,1)>0
                [app.S3,app.S4,app.P4,app.P5,app.P6]=graphInitial(app.UIAxes10,app.UIAxes11,app.UIAxes12,Data,Filtered,Centers,Radii);
                [app.S5,app.S6]=graphCircles(app.UIAxes10,app.UIAxes12,DCircles,DRadii,'r');
            else
                [~,~,app.P4,app.P5,app.P6]=graphInitial(app.UIAxes10,app.UIAxes11,app.UIAxes12,Data,Filtered,Centers,Radii);
                [app.S5,app.S6]=graphCircles(app.UIAxes10,app.UIAxes12,DCircles,DRadii,'r');
            end
           
            
        end
        
        function associatedChanged(app)
            set(app.NAssociated,'AlphaData',0,'FaceAlpha',0,'EdgeAlpha',0);
            for b = 1:2
                for i = 1:size(app.NAssociated,1)
                    set(app.RAssociated(b).associated(i).gobject,'AlphaData',0,'FaceAlpha',0,'EdgeAlpha',0);
                end
            end
            if app.GraphDropDown.Value==1
                set(app.NAssociated,'AlphaData',1,'FaceAlpha',1);
                for b = 1:2
                for i = 1:size(app.NAssociated,1)
                    set(app.RAssociated(b).associated(i).gobject,'AlphaData',1,'FaceAlpha',1);
                end
                end
            else
                set(app.NAssociated(app.GraphDropDown.Value-1),'AlphaData',1,'FaceAlpha',1);
                for b = 1:2
                    set(app.RAssociated(b).associated(app.GraphDropDown.Value-1).gobject,'AlphaData',1,'FaceAlpha',1);
                end
            end


        end
        
        function associatedRun(app)
            A=[];
            for i = 1:size(app.NAssociated,1)
                A=[A;sprintf('Associated set # %i',i)];
            end
            
            app.GraphDropDown.Items=["All Associated Nuclei";A];
            app.GraphDropDown.ItemsData=[1:i+1];
        end
        
        function toGraphML(app)
           
           app.MLFigure= graphMachineLearning(app.P13,app.S1);
           
        end


        function updateT45(app)
            [app.NAssociated,app.RAssociated]=associateNR(app,app.ribbon,app.newCenters,app.mu,app.voxel,app.UIAxes17,str2double(app.RRadiusEditField.Value));
            app.UIAxes17.XLabel.String='X(micron)';
            app.UIAxes17.YLabel.String='Y(micron)';
            app.UIAxes17.ZLabel.String='Z(micron)';
            associatedRun(app);
            [app.R1Scat,app.R2Scat]=representNAndR(app.NAssociated,app.RAssociated,app.UIAxes18);
            app.UIAxes18.XLabel.String='X(micron)';
            app.UIAxes18.YLabel.String='Y(micron)';
            app.UIAxes18.ZLabel.String='Z(micron)';
            app.channelArray=[app.NucleusChEditField.Value,app.PresynapticChEditField.Value,app.PostsynapticChEditField.Value];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FilenameEditFieldValueChanged(app, event)
         
        end

        % Button pushed function: UpdatestartingInfoButton
        function UpdatestartingInfoButtonPushed(app, event)
           
                    app.channelArray=[app.NucleusChEditField.Value,app.PresynapticChEditField.Value,app.PostsynapticChEditField.Value];


            [app.filteredData,app.NCenters,app.NRadii,app.presynapticPositions,app.postsynapticPositions,app.discardedN,app.discardedRPre,app.discardedRPost,app.ribbon]=analyzeData( ...
                app.NucleusChEditField.Value,app.PresynapticChEditField.Value,app.PostsynapticChEditField.Value,[app.ThresholdNEditField.Value, ...
                app.ThresholdPreEditField.Value,app.ThresholdpostEditField.Value,.1],[app.NMedRangeEditField.Value,app.NMedRangeEditField.Value;app.RMedRangeEditField.Value,app.RMedRangeEditField.Value;app.RMedRangeEditField.Value,app.RMedRangeEditField.Value;6,6], ...
                app.NStartValueEditField.Value,app.NStopValueEditField.Value,app.SensitivityNEditField.Value,[app.NminRangeEditField.Value,app.NmaxRangeEditField.Value],[app.RminRangeEditField.Value,app.RmaxRangeEditField.Value], ...
                [str2double(app.NRadiusMaxEditField.Value)-40,str2double(app.NRadiusMaxEditField.Value)],app.EpsilonEditField.Value,app.MinRSizeEditField.Value,app.voxel,app.dimensions,app.plainData);
            
                app.newCenters=app.NCenters;
                app.mu=app.NRadii;


        end

        % Value changed function: xrotationEditField
        function xrotationEditFieldValueChanged(app, event)


            value = app.xrotationEditField.Value;
            app.xRot=value;
        end

        % Value changed function: yrotationEditField
        function yrotationEditFieldValueChanged(app, event)
            value = app.yrotationEditField.Value;
            app.yRot=value;
        end

        % Value changed function: ThresholdNEditField
        function ThresholdNEditFieldValueChanged(app, event)
            value = app.ThresholdNEditField.Value;
            app.thresholdN=value;
        end

        % Value changed function: ThresholdPreEditField
        function ThresholdPreEditFieldValueChanged(app, event)
            value = app.ThresholdPreEditField.Value;
            app.thresholdR1=value;
        end

        % Value changed function: ThresholdpostEditField
        function ThresholdpostEditFieldValueChanged(app, event)
            value = app.ThresholdpostEditField.Value;
            app.thresholdR2=value;
        end

        % Value changed function: NStartValueEditField
        function NStartValueEditFieldValueChanged(app, event)
            value = app.NStartValueEditField.Value;
            app.startValue=value;
        end

        % Value changed function: NStopValueEditField
        function NStopValueEditFieldValueChanged(app, event)
            value = app.NStopValueEditField.Value;
            app.stopValue=value;
        end

        % Value changed function: RminRangeEditField
        function RminRangeEditFieldValueChanged(app, event)
            value = app.RminRangeEditField.Value;
            app.range2(1)=value;
        end

        % Value changed function: NminRangeEditField
        function NminRangeEditFieldValueChanged(app, event)
            value = app.NminRangeEditField.Value;
            app.range1(1)=value;
        end

        % Value changed function: NmaxRangeEditField
        function NmaxRangeEditFieldValueChanged(app, event)
            value = app.NmaxRangeEditField.Value;
            app.range1(2)=value;
        end

        % Value changed function: RmaxRangeEditField
        function RmaxRangeEditFieldValueChanged(app, event)
            value = app.RmaxRangeEditField.Value;
            app.range2(2)=value;
        end

        % Value changed function: PostsynapticChEditField
        function PostsynapticChEditFieldValueChanged(app, event)
            value = app.PostsynapticChEditField.Value;
            app.r1Slice=value;
        end

        % Value changed function: NucleusChEditField
        function NucleusChEditFieldValueChanged(app, event)
            value = app.NucleusChEditField.Value;
            app.nSlice=value;
        end

        % Value changed function: PresynapticChEditField
        function PresynapticChEditFieldValueChanged(app, event)
            value = app.PresynapticChEditField.Value;
            app.r2Slice=value;
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            
            value = app.FileNameEditField.Value;
            app.dataFile=value;
        end

        % Button pushed function: loaddatafromworkspaceButton
        function loaddatafromworkspaceButtonPushed(app, event)
            %load ('loadData.m', 'newCenters', 'mu', 'ribbon', 'data');
            app.NCenters=evalin('base','newCenters');
            app.NRadii=evalin('base','mu');
            app.plainData=evalin('base','plainData');
            app.filteredData=evalin('base','allFiltered');
            %dropDownMatrix=[1:round((app.NStopValueEditField.Value+1-app.NStartValueEditField.Value)/4)];
%             app.presynapticPositions=evalin('base','sendPre');
%             app.postsynapticPositions=evalin('base','sendPost');
            app.voxel=evalin('base','voxel');
            app.dimensions=evalin('base','dimensions');
            app.minMax=evalin('base','minMax');
            app.presynapticPositions=evalin('base','sendPre');
            app.postsynapticPositions=evalin('base','sendPost');
            app.discardedN=evalin('base','discardedN');
            app.discardedRPre=evalin('base','discardedRPre');
            app.discardedRPost=evalin('base','discardedRPost');
            app.ribbon=evalin('base','ribbon');
            app.newCenters=evalin('base','newCenters');
            app.mu=evalin('base','mu');


            updateT45(app);

            
        end

        % Callback function
        function GraphSliceDropDownValueChanged(app, event)
            value = app.GraphSliceDropDown.Value;
             
        end

        % Callback function
        function GraphSliceEditFieldValueChanged(app, event)
            
        end

        % Value changed function: GraphSliceSpinner
        function GraphSliceSpinnerValueChanged(app, event)
            value = app.GraphSliceSpinner.Value;
            channel=str2double(app.OrganDropDown.Value);
            graphFinal(app,value,channel);
            
%             graphRibbon(app.UIAxes4,app.UIAxes5,app.UIAxes6,app.presynapticPositions(value).points,app.data(:,:,round(value),app.PresynapticChEditField.Value),app.filteredData(:,:,round(value),app.PresynapticChEditField.Value));
%                         graphRibbon(app.UIAxes7,app.UIAxes8,app.UIAxes9,app.postsynapticPositions(value).points,app.data(:,:,round(value),app.PostsynapticChEditField.Value),app.filteredData(:,:,round(value),app.PostsynapticChEditField.Value));
% 
% %             
% %             graphDetected(app.UIAxes7,app.UIAxes8,app.UIAxes9,app.newCenters,app.mu,app.ribbon,app.data(:,:,double(round(value)),app.PresynapticChEditField.Value),round(value), app.NucleusChEditField.Value,app.PresynapticChEditField.Value,app.PostsynapticChEditField.Value, ...
% %             app.filteredData(:,:,round(value),app.PresynapticChEditField.Value),[app.NminRangeEditField.Value,app.NmaxRangeEditField.Value],[app.RminRangeEditField.Value,app.RmaxRangeEditField.Value],app.NStartValueEditField.Value,app.presynapticPositions,app.postsynapticPositions);
%             
%             graphNuclei(app.UIAxes10,app.UIAxes11,app.UIAxes12,app.newCenters,app.mu,app.data(:,:,round(value),app.NucleusChEditField.Value),round(value),app.filteredData(:,:,round(value)));


        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [app.voxel,app.dimensions,app.minMax,app.plainData]=initialize(app.FileNameEditField.Value,app.NucleusChEditField.Value,app.PresynapticChEditField.Value,app.PostsynapticChEditField.Value,app.LastChEditField.Value);
            
        end

        % Callback function
        function LookatChannelDropDownValueChanged(app, event)
            
        end

        % Button pushed function: Graph1sliceButton
        function Graph1sliceButtonPushed(app, event)

            graphOne(app);
            
            
        end

        % Value changed function: SliceSpinner
        function SliceSpinnerValueChanged(app, event)
            value = app.SliceSpinner.Value;
            
            if value>app.dimensions(3)
                app.SliceSpinner.Value=app.dimensions(3);
            end
            graphOne(app);
        end

        % Value changed function: ShowCirclesButton
        function ShowCirclesButtonValueChanged(app, event)
            value = app.ShowCirclesButton.Value;            
            showCircles(app,value);
            
        end

        % Value changing function: ContrastSlider
        function ContrastSliderValueChanging(app, event)
             changingValue = event.Value;
             app.P13.CData=contrastFunc(app,changingValue,app.P13BrightnessAdjusted);

        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,changingValue,app.P13Original);
        end

        % Callback function
        function Graph3DpixelsButtonPushed(app, event)
            [app.graphPre,app.graphPost,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,str2double(app.RRadiusEditField.Value));
        end

        % Value changed function: Graph3DDropDown
        function Graph3DDropDownValueChanged(app, event)
            value = app.Graph3DDropDown.Value;
            if value=='Pixels'
                [app.graphPre,app.graphPost,app.graphNuc,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,0,str2double(app.RRadiusEditField.Value));
                app.UIAxes16.XLabel.String='X(Pixels)';
                app.UIAxes16.YLabel.String='Y(Pixels)';
                app.UIAxes16.ZLabel.String='Z(Slice)';
            elseif value=='Scaled'
                [app.graphPre,app.graphPost,app.graphNuc,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,1,str2double(app.RRadiusEditField.Value));
                app.UIAxes16.XLabel.String='X(micron)';
                app.UIAxes16.YLabel.String='Y(micron)';
                app.UIAxes16.ZLabel.String='Z(micron)';
            end

        end

        % Callback function
        function ButtonPushed(app, event)
            app.GraphDropDown.Items=["All Associated Nuclei","another"];
            app.GraphDropDown.ItemsData=[1,2];
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
            value = app.GraphDropDown.Value;
            associatedChanged(app);
        end

        % Button pushed function: 
        % SetCirclesdoNOTexitthiswindowButton
        function SetCirclesdoNOTexitthiswindowButtonPushed(app, event)
            toGraphML(app);
            
        end

        % Button pushed function: UseCirclesButton
        function UseCirclesButtonPushed(app, event)
            if app.OrganDropDown.Value=='1'
                settingsArrayN=bestFitSettingsN(app.MLFigure,app.plainData(:,:,app.SliceSpinner.Value,str2double(app.OrganDropDown.Value)),app.ThresholdNEditField.Value,[app.NMedRangeEditField.Value,app.NMedRangeEditField.Value], ...
                    [app.NminRangeEditField.Value,app.NmaxRangeEditField.Value],[str2double(app.NRadiusMaxEditField.Value)-40,str2double(app.NRadiusMaxEditField.Value)] ...
                    ,app.SensitivityNEditField.Value,app.minMax(2,1),app.minMax(2,2));
            elseif app.OrganDropDown.Value=='2'
                settingsArrayR1=bestFitSettingsR(app.MLFigure,app.plainData(:,:,app.SliceSpinner.Value,str2double(app.OrganDropDown.Value)),[app.RMedRangeEditField.Value,app.RMedRangeEditField.Value], ...
                    app.ThresholdPreEditField.Value,app.EpsilonEditField.Value,app.MinRSizeEditField.Value,[app.RminRangeEditField.Value,app.RmaxRangeEditField.Value] ...
                    ,str2double(app.RRadiusEditField.Value),app.minMax(3,1),app.minMax(3,2))
            elseif app.OrganDropDown.Value=='3'
                
            end
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            value = app.BackgroundDropDown.Value;
            f=figure;
            a=axes(f);
            
            [A,B,C,~,~]=graph3D(a,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,0,str2double(app.RRadiusEditField.Value));
            
            set(C(:,1),'FaceAlpha',0,'EdgeColor','r','EdgeAlpha',.15);
            set(A(:,1),'FaceAlpha',.4);
            set(B(:,1),'FaceAlpha',.4);
            
            m=[app.NucleusChEditField.Value,app.PresynapticChEditField.Value,app.PostsynapticChEditField.Value];
            g=graphFinal3D(a,app.plainData(:,:,:,m(str2double(value))),app.NStartValueEditField.Value,app.NStopValueEditField.Value,1);
            set(f,'WindowScrollWheelFcn',{@getThatScroll,g});
            

        end

        % Value changed function: CheckThatDataDropDown
        function CheckThatDataDropDownValueChanged(app, event)
            value = app.CheckThatDataDropDown.Value;
            
            f=figure;
            a=axes(f);


            m=[app.NucleusChEditField.Value,app.PresynapticChEditField.Value,app.PostsynapticChEditField.Value];
            g=graphFinal3D(a,app.plainData(:,:,:,m(str2double(value))),app.NStartValueEditField.Value,app.NStopValueEditField.Value,0,app);


            app.Scat3D=gobjects();
            view(a,0,90)
            for slice=1:sum(isgraphics(g))
                [Radii,Centers,~,~]=findStats(app,str2double(app.CheckThatDataDropDown.Value),slice);

                if size(Centers,1)>0
                
                    [app.Scat3D(slice,1)]=graphCircles3D(a,Centers,Radii,slice,'r');
                end
            end
            
            set(f,'WindowScrollWheelFcn',{@getThatScroll,g,app});
            set(f,'WindowButtonDownFcn',{@addRibbon,g,app});
            set(f,'CloseRequestFcn',{updateT45auto,app});
            
            indices=find(isgraphics(app.Scat3D(:,1))==1);
            set(app.Scat3D(indices,1),'ButtonDownFcn',{@removeRibbon,app,g});
        end

        % Button pushed function: UpdateRibbonsButton
        function UpdateRibbonsButtonPushed(app, event)
            updateT45(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fileName=app.SaveToEditField.Value;
            nucleusData=[app.newCenters,app.mu];
            ribbonData=app.ribbon;
            saveData(fileName,nucleusData,ribbonData,app.voxel,app.R1Scat,app.R2Scat,app.NAssociated,app.RAssociated);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreliminaryViewingTab = uitab(app.TabGroup);
            app.PreliminaryViewingTab.Title = 'Preliminary Viewing';
            app.ShowCirclesButton = uibutton(app.PreliminaryViewingTab, 'state');
            app.ShowCirclesButton.ValueChangedFcn = createCallbackFcn(app, @ShowCirclesButtonValueChanged, true);
            app.ShowCirclesButton.Text = 'Show Circles';
            app.ContrastSliderLabel = uilabel(app.PreliminaryViewingTab);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.PreliminaryViewingTab);
            app.ContrastSlider.Limits = [0 0.4];
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @ContrastSliderValueChanging, true);
            app.BrightnessSliderLabel = uilabel(app.PreliminaryViewingTab);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.PreliminaryViewingTab);
            app.BrightnessSlider.Limits = [0 1];
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.UIAxes13 = uiaxes(app.PreliminaryViewingTab);
            title(app.UIAxes13, '')
            xlabel(app.UIAxes13, '')
            ylabel(app.UIAxes13, '')
            app.UIAxes13.XTick = [];
            app.UIAxes13.YTick = [];
            app.UIAxes14 = uiaxes(app.PreliminaryViewingTab);
            title(app.UIAxes14, '')
            xlabel(app.UIAxes14, '')
            ylabel(app.UIAxes14, '')
            app.UIAxes14.XTick = [];
            app.UIAxes14.YTick = [];
            app.UIAxes15 = uiaxes(app.PreliminaryViewingTab);
            title(app.UIAxes15, '')
            xlabel(app.UIAxes15, 'X')
            ylabel(app.UIAxes15, 'Y')
            app.UIAxes15.XTickLabel = '';
            app.UIAxes15.YTickLabel = '';
            app.SetCirclesdoNOTexitthiswindowButton = uibutton(app.PreliminaryViewingTab, 'push');
            app.SetCirclesdoNOTexitthiswindowButton.ButtonPushedFcn = createCallbackFcn(app, @SetCirclesdoNOTexitthiswindowButtonPushed, true);
            app.SetCirclesdoNOTexitthiswindowButton.Text = 'Set Circles(do NOT exit this window)';
            app.UseCirclesButton = uibutton(app.PreliminaryViewingTab, 'push');
            app.UseCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @UseCirclesButtonPushed, true);
            app.UseCirclesButton.Text = 'Use Circles';
            app.AfterAnalysisTab = uitab(app.TabGroup);
            app.AfterAnalysisTab.Title = 'After Analysis';
            app.UIAxes10 = uiaxes(app.AfterAnalysisTab);
            title(app.UIAxes10, '')
            xlabel(app.UIAxes10, '')
            ylabel(app.UIAxes10, '')
            app.UIAxes10.XTick = [];
            app.UIAxes10.YTick = [];
            app.UIAxes11 = uiaxes(app.AfterAnalysisTab);
            title(app.UIAxes11, '')
            xlabel(app.UIAxes11, '')
            ylabel(app.UIAxes11, '')
            app.UIAxes11.XTick = [];
            app.UIAxes11.YTick = [];
            app.UIAxes12 = uiaxes(app.AfterAnalysisTab);
            title(app.UIAxes12, '')
            xlabel(app.UIAxes12, '')
            ylabel(app.UIAxes12, '')
            app.UIAxes12.XTick = [];
            app.UIAxes12.YTick = [];
            app.CheckThatDataDropDownLabel = uilabel(app.AfterAnalysisTab);
            app.CheckThatDataDropDownLabel.Text = 'Check That Data!';
            app.CheckThatDataDropDown = uidropdown(app.AfterAnalysisTab);
            app.CheckThatDataDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic'};
            app.CheckThatDataDropDown.ItemsData = {'1', '2', '3'};
            app.CheckThatDataDropDown.ValueChangedFcn = createCallbackFcn(app, @CheckThatDataDropDownValueChanged, true);
            app.CheckThatDataDropDown.Tooltip = {'This is where you can manuaally add ribbons. doulbe left click to add and right click to remove(although you may have to rotate the view a tiny bit as it is a bit finnicky).'; ''; 'IT IS VERY IMPORTANT THAT YOU CHECK ALL THE ORGANS YOU ARE LOOKING FOR WITH THIS DROPDOWN.'};
            app.CheckThatDataDropDown.Value = '1';
            app.DGraphTab = uitab(app.TabGroup);
            app.DGraphTab.Title = '3D Graph';
            app.UIAxes16 = uiaxes(app.DGraphTab);
            title(app.UIAxes16, '3D Graph')
            xlabel(app.UIAxes16, 'X(micron)')
            ylabel(app.UIAxes16, 'Y(micron)')
            app.Graph3DDropDownLabel = uilabel(app.DGraphTab);
            app.Graph3DDropDownLabel.Text = 'Graph 3D';
            app.Graph3DDropDown = uidropdown(app.DGraphTab);
            app.Graph3DDropDown.Items = {'Pixels', 'Scaled', ''};
            app.Graph3DDropDown.ValueChangedFcn = createCallbackFcn(app, @Graph3DDropDownValueChanged, true);
            app.Graph3DDropDown.Tooltip = {'Plot a 3D graph of the nuclei and ribbon locations.'};
            app.Graph3DDropDown.Value = 'Scaled';
            app.BackgroundDropDownLabel = uilabel(app.DGraphTab);
            app.BackgroundDropDownLabel.Text = 'Background:';
            app.BackgroundDropDown = uidropdown(app.DGraphTab);
            app.BackgroundDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic'};
            app.BackgroundDropDown.ItemsData = {'1', '2', '3'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Tooltip = {'Plot a 3d representation of the nuclei and ribbons, but with one of the channels as the backgrund.'};
            app.BackgroundDropDown.Value = '1';
            app.NoOverlapTab = uitab(app.TabGroup);
            app.NoOverlapTab.Title = 'NoOverlap';
            app.UIAxes17 = uiaxes(app.NoOverlapTab);
            title(app.UIAxes17, 'After Removing Overlapping Nuclei')
            xlabel(app.UIAxes17, 'X(micron)')
            ylabel(app.UIAxes17, 'Y(micron)')
            app.GraphDropDownLabel = uilabel(app.NoOverlapTab);
            app.GraphDropDownLabel.Text = 'Graph:';
            app.GraphDropDown = uidropdown(app.NoOverlapTab);
            app.GraphDropDown.Items = {'All Associated Nuclei'};
            app.GraphDropDown.ItemsData = {'1'};
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.GraphDropDown.Tooltip = {'This allows you to choose which nuclei/ribbons you want to look at. It automaticaly removes the nuclei that overlap as well as their associated nuclei.'};
            app.GraphDropDown.Value = '1';
            app.UpdateRibbonsButton = uibutton(app.NoOverlapTab, 'push');
            app.UpdateRibbonsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateRibbonsButtonPushed, true);
            app.UpdateRibbonsButton.Tooltip = {'I think this button is redundant, but press it before looking at the graph on this tab.'};
            app.UpdateRibbonsButton.Text = 'Update Ribbons';
            app.RepresentativeTab = uitab(app.TabGroup);
            app.RepresentativeTab.Title = 'Representative';
            app.UIAxes18 = uiaxes(app.RepresentativeTab);
            title(app.UIAxes18, 'Representative Nucleus and Ribbon Positions')
            xlabel(app.UIAxes18, 'X(micron)')
            ylabel(app.UIAxes18, 'Y(micron)')
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.FileNameEditFieldLabel = uilabel(app.SettingsTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SettingsTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Tooltip = {'Name of the data file. Must be .CZI'};
            app.FileNameEditField.Value = 'IB09MidControlLeft2.czi';
            app.ThresholdNEditFieldLabel = uilabel(app.SettingsTab);
            app.ThresholdNEditFieldLabel.Text = 'Threshold(N)';
            app.ThresholdNEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ThresholdNEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdNEditFieldValueChanged, true);
            app.ThresholdNEditField.Tooltip = {'The threshold for the nucleus. Increase to decrease noise detection. Decrease to increase nucleus accuracy.'};
            app.ThresholdNEditField.Value = 0.08;
            app.ThresholdPreEditFieldLabel = uilabel(app.SettingsTab);
            app.ThresholdPreEditFieldLabel.Text = 'Threshold(Pre)';
            app.ThresholdPreEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ThresholdPreEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdPreEditFieldValueChanged, true);
            app.ThresholdPreEditField.Tooltip = {'The threshold for the presynaptic ribbons. Increase to decrease noise detection. Decrease to increase  accuracy.'};
            app.ThresholdPreEditField.Value = 0.06;
            app.ThresholdpostEditFieldLabel = uilabel(app.SettingsTab);
            app.ThresholdpostEditFieldLabel.Text = 'Threshold(post)';
            app.ThresholdpostEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ThresholdpostEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdpostEditFieldValueChanged, true);
            app.ThresholdpostEditField.Tooltip = {'The threshold for the postsynaptic ribbons. Increase to decrease noise detection. Decrease to increase  accuracy.'};
            app.ThresholdpostEditField.Value = 0.07;
            app.NminRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.NminRangeEditFieldLabel.Text = 'N min. Range';
            app.NminRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NminRangeEditField.ValueChangedFcn = createCallbackFcn(app, @NminRangeEditFieldValueChanged, true);
            app.NminRangeEditField.Tooltip = {'The Y range minimum for nucleus detection. Use this to limit which areas of the slice nuclei are looked for in.'};
            app.NminRangeEditField.Value = 1200;
            app.NmaxRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.NmaxRangeEditFieldLabel.Text = 'N max. Range';
            app.NmaxRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NmaxRangeEditField.ValueChangedFcn = createCallbackFcn(app, @NmaxRangeEditFieldValueChanged, true);
            app.NmaxRangeEditField.Tooltip = {'The Y range maximum for nucleus detection. Use this to limit which areas of the slice nuclei are looked for in.'};
            app.NmaxRangeEditField.Value = 1500;
            app.RminRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.RminRangeEditFieldLabel.Text = 'R min. Range';
            app.RminRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RminRangeEditField.ValueChangedFcn = createCallbackFcn(app, @RminRangeEditFieldValueChanged, true);
            app.RminRangeEditField.Tooltip = {'The Y range minimum for ribbon detection. Use this to limit which areas of the slice ribbons are looked for in.'};
            app.RminRangeEditField.Value = 1200;
            app.RmaxRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.RmaxRangeEditFieldLabel.Text = 'R max. Range';
            app.RmaxRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RmaxRangeEditField.ValueChangedFcn = createCallbackFcn(app, @RmaxRangeEditFieldValueChanged, true);
            app.RmaxRangeEditField.Tooltip = {'The Y range maximum for ribbon detection. Use this to limit which areas of the slice ribbons are looked for in.'};
            app.RmaxRangeEditField.Value = 1700;
            app.NucleusChEditFieldLabel = uilabel(app.SettingsTab);
            app.NucleusChEditFieldLabel.Text = 'Nucleus Ch';
            app.NucleusChEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NucleusChEditField.ValueChangedFcn = createCallbackFcn(app, @NucleusChEditFieldValueChanged, true);
            app.NucleusChEditField.Tooltip = {'Entry for what channel the nuclei are in.'};
            app.NucleusChEditField.Value = 1;
            app.PresynapticChEditFieldLabel = uilabel(app.SettingsTab);
            app.PresynapticChEditFieldLabel.Text = 'Presynaptic Ch';
            app.PresynapticChEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PresynapticChEditField.ValueChangedFcn = createCallbackFcn(app, @PresynapticChEditFieldValueChanged, true);
            app.PresynapticChEditField.Tooltip = {'Enter what channel the presynaptic ribbons are in.'};
            app.PresynapticChEditField.Value = 3;
            app.PostsynapticChEditFieldLabel = uilabel(app.SettingsTab);
            app.PostsynapticChEditFieldLabel.Text = 'Postsynaptic Ch';
            app.PostsynapticChEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PostsynapticChEditField.ValueChangedFcn = createCallbackFcn(app, @PostsynapticChEditFieldValueChanged, true);
            app.PostsynapticChEditField.Tooltip = {'Enter the channel that the postsynaptic densities are in.'};
            app.PostsynapticChEditField.Value = 2;
            app.LoadDataButton = uibutton(app.SettingsTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Press this button to load in the data and perform basic analysis.'};
            app.LoadDataButton.Text = 'Load Data';
            app.SliceSpinnerLabel = uilabel(app.SettingsTab);
            app.SliceSpinnerLabel.Text = 'Slice:';
            app.SliceSpinner = uispinner(app.SettingsTab);
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceSpinnerValueChanged, true);
            app.SliceSpinner.Tooltip = {'Use this to look at different slices for preliminary analysis. It caps at the maximum slice. Changing this will automatically update the preliminary graphs(on the "Preliminary Viewing" tab)'};
            app.SliceSpinner.Value = 10;
            app.SensitivityNEditFieldLabel = uilabel(app.SettingsTab);
            app.SensitivityNEditFieldLabel.Text = 'Sensitivity(N)';
            app.SensitivityNEditField = uieditfield(app.SettingsTab, 'numeric');
            app.SensitivityNEditField.Tooltip = {'How sensitive the circle detection algorithm is. Increase for more circles found, decrease for fewer.'};
            app.SensitivityNEditField.Value = 0.972;
            app.Graph1sliceButton = uibutton(app.SettingsTab, 'push');
            app.Graph1sliceButton.ButtonPushedFcn = createCallbackFcn(app, @Graph1sliceButtonPushed, true);
            app.Graph1sliceButton.Tooltip = {'Use this button to graph preliminary data(on the "Preliminary Viewing" tab).'};
            app.Graph1sliceButton.Text = 'Graph 1 slice';
            app.LastChEditFieldLabel = uilabel(app.SettingsTab);
            app.LastChEditFieldLabel.Text = 'Last Ch';
            app.LastChEditField = uieditfield(app.SettingsTab, 'numeric');
            app.LastChEditField.Tooltip = {'The last remaining channel(is not used for anything yet)'};
            app.LastChEditField.Value = 4;
            app.NMedRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.NMedRangeEditFieldLabel.Text = 'N Med Range';
            app.NMedRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NMedRangeEditField.Tooltip = {'Median filter on the nucleus data. Use this to reduce noise. Increasing this will decrease noise, but also lower the detection rate.'};
            app.NMedRangeEditField.Value = 6;
            app.NRadiusMaxEditFieldLabel = uilabel(app.SettingsTab);
            app.NRadiusMaxEditFieldLabel.Text = 'N Radius Max';
            app.NRadiusMaxEditField = uieditfield(app.SettingsTab, 'text');
            app.NRadiusMaxEditField.Tooltip = {'How large the nuclei are(in pixels). The minimum is automatically set at 40 lower. Increase this to reduce the noise, decrease to increase the number of detections'};
            app.NRadiusMaxEditField.Value = '100';
            app.RRadiusEditFieldLabel = uilabel(app.SettingsTab);
            app.RRadiusEditFieldLabel.Text = 'R Radius';
            app.RRadiusEditField = uieditfield(app.SettingsTab, 'text');
            app.RRadiusEditField.Tooltip = {'This does not affect anything except how the ribbons are graphed.'};
            app.RRadiusEditField.Value = '15';
            app.RMedRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.RMedRangeEditFieldLabel.Text = 'R Med Range';
            app.RMedRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RMedRangeEditField.Tooltip = {'Median filter on the ribbon data. Use this to reduce noise. Increasing this will decrease noise, but also lower the detection rate.'};
            app.RMedRangeEditField.Value = 5;
            app.EpsilonEditFieldLabel = uilabel(app.SettingsTab);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.SettingsTab, 'numeric');
            app.EpsilonEditField.Tooltip = {'The search range for ribons. ie, how far apart(in pixels) the ribbons have to be to be considered separate.'};
            app.EpsilonEditField.Value = 8;
            app.MinRSizeEditFieldLabel = uilabel(app.SettingsTab);
            app.MinRSizeEditFieldLabel.Text = 'Min R Size';
            app.MinRSizeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.MinRSizeEditField.Tooltip = {'How many pixels the ribbons must take up after filtering to be considered not noise. Increase to decrease noise, decrease to increase ribbon detection.'};
            app.MinRSizeEditField.Value = 5;
            app.OrganDropDownLabel = uilabel(app.SettingsTab);
            app.OrganDropDownLabel.Text = 'Organ';
            app.OrganDropDown = uidropdown(app.SettingsTab);
            app.OrganDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic', 'Extra'};
            app.OrganDropDown.ItemsData = {'1', '2', '3', '4'};
            app.OrganDropDown.Tooltip = {'This determines what organ is being looked at for analysis. The channel entry for each organ is lower.'};
            app.OrganDropDown.Value = '2';
            app.NStartValueEditFieldLabel = uilabel(app.SettingsTab);
            app.NStartValueEditFieldLabel.Text = 'N Start Value';
            app.NStartValueEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NStartValueEditField.ValueChangedFcn = createCallbackFcn(app, @NStartValueEditFieldValueChanged, true);
            app.NStartValueEditField.Tooltip = {'Set the first slice that the program will look at. Use this to screen out stuff you dont want being used in the analysis.'};
            app.NStartValueEditField.Value = 1;
            app.NStopValueEditFieldLabel = uilabel(app.SettingsTab);
            app.NStopValueEditFieldLabel.Text = 'N Stop Value';
            app.NStopValueEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NStopValueEditField.ValueChangedFcn = createCallbackFcn(app, @NStopValueEditFieldValueChanged, true);
            app.NStopValueEditField.Tooltip = {'Set the slice X lower than the last that the program will look at. Use this to screen out stuff you dont want being used in the analysis.'};
            app.NStopValueEditField.Value = 1;
            app.RStartValueEditFieldLabel = uilabel(app.SettingsTab);
            app.RStartValueEditFieldLabel.Text = 'R Start Value';
            app.RStartValueEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RStartValueEditField.Value = 1;
            app.RStopValueEditFieldLabel = uilabel(app.SettingsTab);
            app.RStopValueEditFieldLabel.Text = 'R Stop Value';
            app.RStopValueEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RStopValueEditField.Value = 1;
            app.MoreSettingsTab = uitab(app.TabGroup2);
            app.MoreSettingsTab.Title = 'More Settings';
            app.xrotationEditFieldLabel = uilabel(app.MoreSettingsTab);
            app.xrotationEditFieldLabel.Text = 'x rotation';
            app.xrotationEditField = uieditfield(app.MoreSettingsTab, 'numeric');
            app.xrotationEditField.ValueChangedFcn = createCallbackFcn(app, @xrotationEditFieldValueChanged, true);
            app.xrotationEditField.Tooltip = {'not yet implemented'};
            app.yrotationEditFieldLabel = uilabel(app.MoreSettingsTab);
            app.yrotationEditFieldLabel.Text = 'y rotation';
            app.yrotationEditField = uieditfield(app.MoreSettingsTab, 'numeric');
            app.yrotationEditField.ValueChangedFcn = createCallbackFcn(app, @yrotationEditFieldValueChanged, true);
            app.yrotationEditField.Tooltip = {'not yet implemented'};
            app.loaddatafromworkspaceButton = uibutton(app.MoreSettingsTab, 'push');
            app.loaddatafromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @loaddatafromworkspaceButtonPushed, true);
            app.loaddatafromworkspaceButton.Tooltip = {'Just for use by Kevin'};
            app.loaddatafromworkspaceButton.Text = 'load data(from workspace)';
            app.UpdatestartingInfoButton = uibutton(app.MoreSettingsTab, 'push');
            app.UpdatestartingInfoButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatestartingInfoButtonPushed, true);
            app.UpdatestartingInfoButton.Tooltip = {'Use this to do the full analysis on the data. It will look for nuclei and ribbons on each slice and use various methods to find the true centers/radii of the nuclei, as well as the locations of ribbons'};
            app.UpdatestartingInfoButton.Text = 'Update starting Info.';
            app.GraphSliceSpinnerLabel = uilabel(app.MoreSettingsTab);
            app.GraphSliceSpinnerLabel.Text = 'Graph Slice:';
            app.GraphSliceSpinner = uispinner(app.MoreSettingsTab);
            app.GraphSliceSpinner.ValueChangedFcn = createCallbackFcn(app, @GraphSliceSpinnerValueChanged, true);
            app.GraphSliceSpinner.Tooltip = {'Use this to look at the final data on the "After Analysis" tab.'};
            app.SaveToEditFieldLabel = uilabel(app.MoreSettingsTab);
            app.SaveToEditFieldLabel.Text = 'Save To:';
            app.SaveToEditField = uieditfield(app.MoreSettingsTab, 'text');
            app.SaveToEditField.Tooltip = {'Enter a filename that you want the program to save to(I recommend the name of the datafile minus the .CZI)'};
            app.SaveButton = uibutton(app.MoreSettingsTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'This actually saves the data to files that are put in a folder. The suffix of each file describes what type of data is in it.'};
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ribbonAnalysisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end